classdef Skynet_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DrinksMenuButtonGroup         matlab.ui.container.ButtonGroup
        SpriteButton_2                matlab.ui.control.Button
        CokeButton                    matlab.ui.control.Button
        VodkaButton                   matlab.ui.control.Button
        RumButton                     matlab.ui.control.Button
        ResumeButton                  matlab.ui.control.Button
        ClearButton                   matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        KUKACollisionDetectionButton  matlab.ui.control.Button
        KUKAJoystickControlButton     matlab.ui.control.StateButton
        UR3JoystickControlButton      matlab.ui.control.StateButton
        ObjectinWorkspaceButton       matlab.ui.control.Button
        EStopToggleSwitchLabel_2      matlab.ui.control.Label
        EStopToggleSwitch             matlab.ui.control.Switch
        EStopButton                   matlab.ui.control.Button
        KUKAQ1SliderLabel             matlab.ui.control.Label
        KUKAQ1Slider                  matlab.ui.control.Slider
        KUKAQ2SliderLabel             matlab.ui.control.Label
        KUKAQ2Slider                  matlab.ui.control.Slider
        KUKAQ3SliderLabel             matlab.ui.control.Label
        KUKAQ3Slider                  matlab.ui.control.Slider
        KUKAQ4SliderLabel             matlab.ui.control.Label
        KUKAQ4Slider                  matlab.ui.control.Slider
        KUKAQ5SliderLabel             matlab.ui.control.Label
        KUKAQ5Slider                  matlab.ui.control.Slider
        KUKAQ6SliderLabel             matlab.ui.control.Label
        KUKAQ6Slider                  matlab.ui.control.Slider
        UR3Q1SliderLabel              matlab.ui.control.Label
        UR3Q1Slider                   matlab.ui.control.Slider
        UR3Q2SliderLabel              matlab.ui.control.Label
        UR3Q2Slider                   matlab.ui.control.Slider
        UR3Q3SliderLabel              matlab.ui.control.Label
        UR3Q3Slider                   matlab.ui.control.Slider
        UR3Q4SliderLabel              matlab.ui.control.Label
        UR3Q4Slider                   matlab.ui.control.Slider
        UR3Q5SliderLabel              matlab.ui.control.Label
        UR3Q5Slider                   matlab.ui.control.Slider
        UR3Q6SliderLabel              matlab.ui.control.Label
        UR3Q6Slider                   matlab.ui.control.Slider
        KUKAJointControlLabel         matlab.ui.control.Label
        UR3JointControlLabel          matlab.ui.control.Label
        XEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        ZEditFieldLabel               matlab.ui.control.Label
        ZEditField                    matlab.ui.control.NumericEditField
        RollEditFieldLabel            matlab.ui.control.Label
        RollEditField                 matlab.ui.control.NumericEditField
        PitchEditFieldLabel           matlab.ui.control.Label
        PitchEditField                matlab.ui.control.NumericEditField
        YawEditFieldLabel             matlab.ui.control.Label
        YawEditField                  matlab.ui.control.NumericEditField
        XEditField_2Label             matlab.ui.control.Label
        XEditField_2                  matlab.ui.control.NumericEditField
        YEditField_2Label             matlab.ui.control.Label
        YEditField_2                  matlab.ui.control.NumericEditField
        ZEditField_2Label             matlab.ui.control.Label
        ZEditField_2                  matlab.ui.control.NumericEditField
        RollEditField_2Label          matlab.ui.control.Label
        RollEditField_2               matlab.ui.control.NumericEditField
        PitchEditField_2Label         matlab.ui.control.Label
        PitchEditField_2              matlab.ui.control.NumericEditField
        YawEditField_2Label           matlab.ui.control.Label
        YawEditField_2                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
      eStopValue = true; % Description
      kukaq = [1.5708   -1.5708         0   -1.5709    3.1416        -1.5708]; % Used for teach function
      ur3q = [1.5708   -1.5708         0   -1.5709    3.1416        -1.5708]; % Used for teach function 
    end

    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VodkaButton
        function VodkaButtonPushed(app, event)
            
            ur3 = evalin('base', 'ur3'); % Used to call the UR3 robot into the button function
            kuka = evalin('base', 'kuka'); % used to call the kuka into the button function 
            Vodka_h = evalin('base', 'Vodka_h');
            vertices = evalin('base', 'vertices');
            BartransformedVertices = evalin('base', 'BartransformedVertices');
            BenchtransformedVertices = evalin('base', 'BenchtransformedVertices');
            transformedVertices = evalin('base', 'transformedVertices');
            Vodka(ur3,kuka,Vodka_h,vertices,transformedVertices,BartransformedVertices,BenchtransformedVertices);
         
        end

        % Button pushed function: RumButton
        function RumButtonPushed(app, event)
            ur3 = evalin('base', 'ur3');
            kuka = evalin('base', 'kuka');
            Rum_h = evalin('base', 'Rum_h');
            vertices = evalin('base', 'vertices');
            BartransformedVertices = evalin('base', 'BartransformedVertices');
            BenchtransformedVertices = evalin('base', 'BenchtransformedVertices');
            transformedVertices = evalin('base', 'transformedVertices');
            Rum(ur3,kuka,Rum_h,vertices,transformedVertices,BartransformedVertices,BenchtransformedVertices);
        end

        % Button pushed function: CokeButton
        function CokeButtonPushed(app, event)
            ur3 = evalin('base', 'ur3');
            kuka = evalin('base', 'kuka');
            Coke_h = evalin('base', 'Coke_h');
            vertices = evalin('base', 'vertices');
            BartransformedVertices = evalin('base', 'BartransformedVertices');
            BenchtransformedVertices = evalin('base', 'BenchtransformedVertices');
            transformedVertices = evalin('base', 'transformedVertices');
            Coke(ur3,kuka,Coke_h,vertices,transformedVertices,BartransformedVertices,BenchtransformedVertices);
        end

        % Button pushed function: SpriteButton_2
        function SpriteButton_2Pushed(app, event)
            ur3 = evalin('base', 'ur3');
            kuka = evalin('base', 'kuka');
            Sprite_h = evalin('base', 'Sprite_h');
            vertices = evalin('base', 'vertices');
            BartransformedVertices = evalin('base', 'BartransformedVertices');
            BenchtransformedVertices = evalin('base', 'BenchtransformedVertices');
            transformedVertices = evalin('base', 'transformedVertices');
            Sprite(ur3,kuka,Sprite_h,vertices,transformedVertices,BartransformedVertices,BenchtransformedVertices);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            clf
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
          hold on
          run('IRAssignment2');
      
        end

        % Button pushed function: KUKACollisionDetectionButton
        function KukaCollisionDetection(app, event)
            kuka = evalin('base', 'kuka');
            BartransformedVertices = evalin('base', 'BartransformedVertices');
            KukaCollisionDetectionExample(kuka,BartransformedVertices);
        end

        % Value changed function: KUKAJoystickControlButton
        function KUKAJoystickControlButtonValueChanged(app, event)
            value = app.KUKAJoystickControlButton.Value;
            kuka = evalin('base', 'kuka');
            KUKAcontroller(kuka); %  Calls the controller function 
        end

        % Value changed function: UR3JoystickControlButton
        function UR3JoystickControlButtonValueChanged(app, event)
            value = app.UR3JoystickControlButton.Value;
            ur3 = evalin('base', 'ur3');
            UR3controller(ur3);
        end

        % Button pushed function: ObjectinWorkspaceButton
        function ObjectInWorkspace(app, event)
            v = evalin('base', 'v');
            f = evalin('base', 'f');
            fn = evalin('base', 'fn');
            hand = evalin('base', 'hand');
            ObjectInWorkspace(hand,v,f,fn)
            uiwait();  
            
        end

        % Button pushed function: ResumeButton
        function Resume(app, event)
            hand = evalin('base', 'hand');
            ObjectOutOfWorkspace(hand)
            uiresume();
        end

        % Value changed function: EStopToggleSwitch
        function ToggleEstop(app, event)
         
            value = app.EStopToggleSwitch.Value;
            
            if strcmp(value,'Off')
                app.eStopValue = true;
            else
                app.eStopValue = false;
            end
            
        end

        % Button pushed function: EStopButton
        function EStop(app, event)
              if app.eStopValue == true
                uiwait();
            else
                uiresume(); 
                app.EStopToggleSwitch.Value = 'Off';
                app.eStopValue = true
            end
              
            
        end

        % Value changing function: KUKAQ1Slider
        function KUKAQ1SliderValueChanging(app, event)
            kuka = evalin('base', 'kuka');  % Calls kuka into function
            app.kukaq(1) = deg2rad(event.Value); % Changes the first q value to the specified event value from slider
            kuka.model.animate(app.kukaq); % Animates the robot to to the new q value
            transform = kuka.model.fkine(app.kukaq); % Gets end effector location with new q value updated from slider
            RPY = rad2deg(tr2rpy(transform)); % changes transform to rpy values and then converts to degrees
            app.XEditField.Value = transform(1,4); % Updates the text field to 1st value in 4th column of 4 x 4 transform
            app.YEditField.Value = transform(2,4); % " "
            app.ZEditField.Value = transform(3,4);
            app.RollEditField.Value = RPY(1); 
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
            
            %% This function is repeated for all sliders changing the q value location e.g. kuka1(2)... etc.
        end

        % Value changing function: KUKAQ2Slider
        function KUKAQ2SliderValueChanging(app, event)
            kuka = evalin('base', 'kuka');
            app.kukaq(2) = deg2rad(event.Value);
            kuka.model.animate(app.kukaq);
            transform = kuka.model.fkine(app.kukaq);
            RPY = rad2deg(tr2rpy(transform));
            app.XEditField.Value = transform(1,4);
            app.YEditField.Value = transform(2,4);
            app.ZEditField.Value = transform(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
        end

        % Value changing function: KUKAQ3Slider
        function KUKAQ3SliderValueChanging(app, event)
            kuka = evalin('base', 'kuka');
            app.kukaq(3) = deg2rad(event.Value);
            kuka.model.animate(app.kukaq);
            transform = kuka.model.fkine(app.kukaq);
            RPY = rad2deg(tr2rpy(transform));
            app.XEditField.Value = transform(1,4);
            app.YEditField.Value = transform(2,4);
            app.ZEditField.Value = transform(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
            
        end

        % Value changing function: KUKAQ4Slider
        function KUKAQ4SliderValueChanging(app, event)
            kuka = evalin('base', 'kuka');
            app.kukaq(4) = deg2rad(event.Value);
            kuka.model.animate(app.kukaq);
            transform = kuka.model.fkine(app.kukaq);
            RPY = rad2deg(tr2rpy(transform));
            app.XEditField.Value = transform(1,4);
            app.YEditField.Value = transform(2,4);
            app.ZEditField.Value = transform(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
            
        end

        % Value changing function: KUKAQ5Slider
        function KUKAQ5SliderValueChanging(app, event)
            kuka = evalin('base', 'kuka');
            app.kukaq(5) = deg2rad(event.Value);
            kuka.model.animate(app.kukaq);
            transform = kuka.model.fkine(app.kukaq);
            RPY = rad2deg(tr2rpy(transform));
            app.XEditField.Value = transform(1,4);
            app.YEditField.Value = transform(2,4);
            app.ZEditField.Value = transform(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
            
        end

        % Value changing function: KUKAQ6Slider
        function KUKAQ6SliderValueChanging(app, event)
            kuka = evalin('base', 'kuka');
            app.kukaq(6) = deg2rad(event.Value);
            kuka.model.animate(app.kukaq);
            transform = kuka.model.fkine(app.kukaq);
            RPY = rad2deg(tr2rpy(transform));
            app.XEditField.Value = transform(1,4);
            app.YEditField.Value = transform(2,4);
            app.ZEditField.Value = transform(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
            
        end

        % Value changing function: UR3Q1Slider
        function UR3Q1SliderValueChanging(app, event)
            ur3 = evalin('base', 'ur3');
            app.ur3q(1) = deg2rad(event.Value);
            ur3.model.animate(app.ur3q);
            transform = ur3.model.fkine(app.ur3q);
            RPY = rad2deg(tr2rpy(transform));
            app.XEditField_2.Value = transform(1,4);
            app.YEditField_2.Value = transform(2,4);
            app.ZEditField_2.Value = transform(3,4);
            app.RollEditField_2.Value = RPY(1);
            app.PitchEditField_2.Value =RPY(2);
            app.YawEditField_2.Value = RPY(3);
          
        end

        % Value changing function: UR3Q2Slider
        function UR3Q2SliderValueChanging(app, event)
            ur3 = evalin('base', 'ur3');
            app.ur3q(2) = deg2rad(event.Value);
            ur3.model.animate(app.ur3q);
            transform = ur3.model.fkine(app.ur3q);
            RPY = rad2deg(tr2rpy(transform));
            app.XEditField_2.Value = transform(1,4);
            app.YEditField_2.Value = transform(2,4);
            app.ZEditField_2.Value = transform(3,4);
            app.RollEditField_2.Value = RPY(1);
            app.PitchEditField_2.Value =RPY(2);
            app.YawEditField_2.Value = RPY(3);
            
        end

        % Value changing function: UR3Q3Slider
        function UR3Q3SliderValueChanging(app, event)
            ur3 = evalin('base', 'ur3');
            app.ur3q(3) = deg2rad(event.Value);
            ur3.model.animate(app.ur3q);
            transform = ur3.model.fkine(app.ur3q);
            RPY = rad2deg(tr2rpy(transform));
            app.XEditField_2.Value = transform(1,4);
            app.YEditField_2.Value = transform(2,4);
            app.ZEditField_2.Value = transform(3,4);
            app.RollEditField_2.Value = RPY(1);
            app.PitchEditField_2.Value =RPY(2);
            app.YawEditField_2.Value = RPY(3);
            
        end

        % Value changing function: UR3Q4Slider
        function UR3Q4SliderValueChanging(app, event)
            ur3 = evalin('base', 'ur3');
            app.ur3q(4) = deg2rad(event.Value);
            ur3.model.animate(app.ur3q);
            transform = ur3.model.fkine(app.ur3q);
            RPY = rad2deg(tr2rpy(transform));
            app.XEditField_2.Value = transform(1,4);
            app.YEditField_2.Value = transform(2,4);
            app.ZEditField_2.Value = transform(3,4);
            app.RollEditField_2.Value = RPY(1);
            app.PitchEditField_2.Value =RPY(2);
            app.YawEditField_2.Value = RPY(3);
            
        end

        % Value changing function: UR3Q5Slider
        function UR3Q5SliderValueChanging(app, event)
            ur3 = evalin('base', 'ur3');
            app.ur3q(5) = deg2rad(event.Value);
            ur3.model.animate(app.ur3q);
            transform = ur3.model.fkine(app.ur3q);
            RPY = rad2deg(tr2rpy(transform));
            app.XEditField_2.Value = transform(1,4);
            app.YEditField_2.Value = transform(2,4);
            app.ZEditField_2.Value = transform(3,4);
            app.RollEditField_2.Value = RPY(1);
            app.PitchEditField_2.Value =RPY(2);
            app.YawEditField_2.Value = RPY(3);
            
        end

        % Value changing function: UR3Q6Slider
        function UR3Q6SliderValueChanging(app, event)
            ur3 = evalin('base', 'ur3');
            app.ur3q(6) = deg2rad(event.Value);
            ur3.model.animate(app.ur3q);
            transform = ur3.model.fkine(app.ur3q);
            RPY = rad2deg(tr2rpy(transform));
            app.XEditField_2.Value = transform(1,4);
            app.YEditField_2.Value = transform(2,4);
            app.ZEditField_2.Value = transform(3,4);
            app.RollEditField_2.Value = RPY(1);
            app.PitchEditField_2.Value =RPY(2);
            app.YawEditField_2.Value = RPY(3);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DrinksMenuButtonGroup = uibuttongroup(app.UIFigure);
            app.DrinksMenuButtonGroup.Title = 'Drinks Menu';
            app.SpriteButton_2 = uibutton(app.DrinksMenuButtonGroup, 'push');
            app.SpriteButton_2.ButtonPushedFcn = createCallbackFcn(app, @SpriteButton_2Pushed, true);
            app.SpriteButton_2.Text = {'Sprite'; ''};
            app.CokeButton = uibutton(app.DrinksMenuButtonGroup, 'push');
            app.CokeButton.ButtonPushedFcn = createCallbackFcn(app, @CokeButtonPushed, true);
            app.CokeButton.Text = 'Coke';
            app.VodkaButton = uibutton(app.DrinksMenuButtonGroup, 'push');
            app.VodkaButton.ButtonPushedFcn = createCallbackFcn(app, @VodkaButtonPushed, true);
            app.VodkaButton.Text = 'Vodka';
            app.RumButton = uibutton(app.DrinksMenuButtonGroup, 'push');
            app.RumButton.ButtonPushedFcn = createCallbackFcn(app, @RumButtonPushed, true);
            app.RumButton.Text = {'Rum'; ''};
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @Resume, true);
            app.ResumeButton.Text = {'Resume'; ''};
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.KUKACollisionDetectionButton = uibutton(app.UIFigure, 'push');
            app.KUKACollisionDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @KukaCollisionDetection, true);
            app.KUKACollisionDetectionButton.Text = {'KUKA Collision '; 'Detection '; ''};
            app.KUKAJoystickControlButton = uibutton(app.UIFigure, 'state');
            app.KUKAJoystickControlButton.ValueChangedFcn = createCallbackFcn(app, @KUKAJoystickControlButtonValueChanged, true);
            app.KUKAJoystickControlButton.Text = 'KUKA Joystick Control';
            app.UR3JoystickControlButton = uibutton(app.UIFigure, 'state');
            app.UR3JoystickControlButton.ValueChangedFcn = createCallbackFcn(app, @UR3JoystickControlButtonValueChanged, true);
            app.UR3JoystickControlButton.Text = 'UR3 Joystick Control';
            app.ObjectinWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.ObjectinWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectInWorkspace, true);
            app.ObjectinWorkspaceButton.Text = {'Object in Workspace'; ''};
            app.EStopToggleSwitchLabel_2 = uilabel(app.UIFigure);
            app.EStopToggleSwitchLabel_2.Text = {'E-Stop Toggle Switch'; ''};
            app.EStopToggleSwitch = uiswitch(app.UIFigure, 'slider');
            app.EStopToggleSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleEstop, true);
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStop, true);
            app.EStopButton.Text = 'E-Stop';
            app.KUKAQ1SliderLabel = uilabel(app.UIFigure);
            app.KUKAQ1SliderLabel.Text = 'KUKA Q1';
            app.KUKAQ1Slider = uislider(app.UIFigure);
            app.KUKAQ1Slider.Limits = [-360 360];
            app.KUKAQ1Slider.ValueChangingFcn = createCallbackFcn(app, @KUKAQ1SliderValueChanging, true);
            app.KUKAQ2SliderLabel = uilabel(app.UIFigure);
            app.KUKAQ2SliderLabel.Text = 'KUKA Q2';
            app.KUKAQ2Slider = uislider(app.UIFigure);
            app.KUKAQ2Slider.Limits = [-190 45];
            app.KUKAQ2Slider.ValueChangingFcn = createCallbackFcn(app, @KUKAQ2SliderValueChanging, true);
            app.KUKAQ3SliderLabel = uilabel(app.UIFigure);
            app.KUKAQ3SliderLabel.Text = 'KUKA Q3';
            app.KUKAQ3Slider = uislider(app.UIFigure);
            app.KUKAQ3Slider.Limits = [-120 156];
            app.KUKAQ3Slider.ValueChangingFcn = createCallbackFcn(app, @KUKAQ3SliderValueChanging, true);
            app.KUKAQ4SliderLabel = uilabel(app.UIFigure);
            app.KUKAQ4SliderLabel.Text = 'KUKA Q4';
            app.KUKAQ4Slider = uislider(app.UIFigure);
            app.KUKAQ4Slider.Limits = [-185 185];
            app.KUKAQ4Slider.ValueChangingFcn = createCallbackFcn(app, @KUKAQ4SliderValueChanging, true);
            app.KUKAQ5SliderLabel = uilabel(app.UIFigure);
            app.KUKAQ5SliderLabel.Text = 'KUKA Q5';
            app.KUKAQ5Slider = uislider(app.UIFigure);
            app.KUKAQ5Slider.Limits = [-120 120];
            app.KUKAQ5Slider.ValueChangingFcn = createCallbackFcn(app, @KUKAQ5SliderValueChanging, true);
            app.KUKAQ6SliderLabel = uilabel(app.UIFigure);
            app.KUKAQ6SliderLabel.Text = 'KUKA Q6';
            app.KUKAQ6Slider = uislider(app.UIFigure);
            app.KUKAQ6Slider.Limits = [-350 350];
            app.KUKAQ6Slider.ValueChangingFcn = createCallbackFcn(app, @KUKAQ6SliderValueChanging, true);
            app.UR3Q1SliderLabel = uilabel(app.UIFigure);
            app.UR3Q1SliderLabel.Text = 'UR3 Q1';
            app.UR3Q1Slider = uislider(app.UIFigure);
            app.UR3Q1Slider.Limits = [-360 360];
            app.UR3Q1Slider.ValueChangingFcn = createCallbackFcn(app, @UR3Q1SliderValueChanging, true);
            app.UR3Q2SliderLabel = uilabel(app.UIFigure);
            app.UR3Q2SliderLabel.Text = 'UR3 Q2';
            app.UR3Q2Slider = uislider(app.UIFigure);
            app.UR3Q2Slider.Limits = [-360 360];
            app.UR3Q2Slider.ValueChangingFcn = createCallbackFcn(app, @UR3Q2SliderValueChanging, true);
            app.UR3Q3SliderLabel = uilabel(app.UIFigure);
            app.UR3Q3SliderLabel.Text = 'UR3 Q3';
            app.UR3Q3Slider = uislider(app.UIFigure);
            app.UR3Q3Slider.Limits = [-360 360];
            app.UR3Q3Slider.ValueChangingFcn = createCallbackFcn(app, @UR3Q3SliderValueChanging, true);
            app.UR3Q4SliderLabel = uilabel(app.UIFigure);
            app.UR3Q4SliderLabel.Text = 'UR3 Q4';
            app.UR3Q4Slider = uislider(app.UIFigure);
            app.UR3Q4Slider.Limits = [-360 360];
            app.UR3Q4Slider.ValueChangingFcn = createCallbackFcn(app, @UR3Q4SliderValueChanging, true);
            app.UR3Q5SliderLabel = uilabel(app.UIFigure);
            app.UR3Q5SliderLabel.Text = 'UR3 Q5';
            app.UR3Q5Slider = uislider(app.UIFigure);
            app.UR3Q5Slider.Limits = [-360 360];
            app.UR3Q5Slider.ValueChangingFcn = createCallbackFcn(app, @UR3Q5SliderValueChanging, true);
            app.UR3Q6SliderLabel = uilabel(app.UIFigure);
            app.UR3Q6SliderLabel.Text = 'UR3 Q6';
            app.UR3Q6Slider = uislider(app.UIFigure);
            app.UR3Q6Slider.Limits = [-360 360];
            app.UR3Q6Slider.ValueChangingFcn = createCallbackFcn(app, @UR3Q6SliderValueChanging, true);
            app.KUKAJointControlLabel = uilabel(app.UIFigure);
            app.KUKAJointControlLabel.Text = 'KUKA Joint Control';
            app.UR3JointControlLabel = uilabel(app.UIFigure);
            app.UR3JointControlLabel.Text = 'UR3 Joint Control';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.4g';
            app.XEditField.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.4g';
            app.YEditField.Editable = 'off';
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueDisplayFormat = '%.4g';
            app.ZEditField.Editable = 'off';
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditField.ValueDisplayFormat = '%.4g';
            app.RollEditField.Editable = 'off';
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditField.ValueDisplayFormat = '%.4g';
            app.PitchEditField.Editable = 'off';
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditField.ValueDisplayFormat = '%.4g';
            app.YawEditField.Editable = 'off';
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_2.ValueDisplayFormat = '%.4g';
            app.XEditField_2.Editable = 'off';
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2.ValueDisplayFormat = '%.4g';
            app.YEditField_2.Editable = 'off';
            app.ZEditField_2Label = uilabel(app.UIFigure);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_2.ValueDisplayFormat = '%.4g';
            app.ZEditField_2.Editable = 'off';
            app.RollEditField_2Label = uilabel(app.UIFigure);
            app.RollEditField_2Label.Text = 'Roll';
            app.RollEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RollEditField_2.ValueDisplayFormat = '%.4g';
            app.RollEditField_2.Editable = 'off';
            app.PitchEditField_2Label = uilabel(app.UIFigure);
            app.PitchEditField_2Label.Text = 'Pitch';
            app.PitchEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditField_2.ValueDisplayFormat = '%.4g';
            app.PitchEditField_2.Editable = 'off';
            app.YawEditField_2Label = uilabel(app.UIFigure);
            app.YawEditField_2Label.Text = 'Yaw';
            app.YawEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YawEditField_2.ValueDisplayFormat = '%.4g';
            app.YawEditField_2.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Skynet_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end